classdef Sbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        time_to_next_cycle            matlab.ui.control.Label
        ncs_left                      matlab.ui.control.Label
        TimetonextcycleLabel          matlab.ui.control.Label
        NumberofcyclesleftLabel       matlab.ui.control.Label
        shock4                        matlab.ui.control.CheckBox
        shock3                        matlab.ui.control.CheckBox
        shock2                        matlab.ui.control.CheckBox
        shock1                        matlab.ui.control.CheckBox
        tone3                         matlab.ui.control.CheckBox
        tone3_freq                    matlab.ui.control.DropDown
        Tone1PitchkHzLabel_2          matlab.ui.control.Label
        tone4                         matlab.ui.control.CheckBox
        tone4_freq                    matlab.ui.control.DropDown
        Tone2PitchkHzLabel_2          matlab.ui.control.Label
        dir                           matlab.ui.control.Button
        mouse_id                      matlab.ui.control.EditField
        MouseIDLabel                  matlab.ui.control.Label
        resdir                        matlab.ui.control.EditField
        ResDirEditFieldLabel          matlab.ui.control.Label
        save_video                    matlab.ui.control.CheckBox
        preview                       matlab.ui.control.Button
        initial_delay                 matlab.ui.control.NumericEditField
        InitialDelaysLabel            matlab.ui.control.Label
        volume                        matlab.ui.control.NumericEditField
        ToneVolumeLabel               matlab.ui.control.Label
        STOPButton                    matlab.ui.control.Button
        shock_current                 matlab.ui.control.NumericEditField
        ShockCurrentmALabel           matlab.ui.control.Label
        tone_before_shock             matlab.ui.control.CheckBox
        shock_before_tone             matlab.ui.control.CheckBox
        tone_duration_min             matlab.ui.control.NumericEditField
        ToneDurationsLabel            matlab.ui.control.Label
        tone_duration_max             matlab.ui.control.NumericEditField
        tone2                         matlab.ui.control.CheckBox
        tone1                         matlab.ui.control.CheckBox
        tone2_freq                    matlab.ui.control.DropDown
        Tone2PitchkHzLabel            matlab.ui.control.Label
        tone1_freq                    matlab.ui.control.DropDown
        Tone1PitchkHzLabel            matlab.ui.control.Label
        number_of_cycles              matlab.ui.control.NumericEditField
        NumberOfCyclesLabel           matlab.ui.control.Label
        STARTButton                   matlab.ui.control.Button
        delay_min                     matlab.ui.control.NumericEditField
        DelaysLabel                   matlab.ui.control.Label
        delay_max                     matlab.ui.control.NumericEditField
        cycle_interval_min            matlab.ui.control.NumericEditField
        CycleIntervalsLabel           matlab.ui.control.Label
        cycle_interval_max            matlab.ui.control.NumericEditField
        shock_duration_max            matlab.ui.control.NumericEditField
        shock_duration_min            matlab.ui.control.NumericEditField
        ShockDurationsEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(['.' filesep 'src' filesep]));
            loadStatus(app,'gui_settings_shock.mat');
            app.UIFigure.Name = 'Shock1';
            app.STARTButton.Visible = 'on';
            app.STOPButton.Visible = 'off';
            init();
        end

        % Callback function
        function ONButtonPushed(app, event)
            global arduino;
            fprintf('On\n');
         fprintf(arduino,'%c','a');
%          fprintf(arduino,'%c',2);
         fprintf(arduino,'%c',4);
            
        end

        % Callback function
        function OFFButtonPushed(app, event)
            global arduino;
            
            fprintf('Off\n');
            
         fprintf(arduino,'%c','b');


%              write(arduino,'*',"char");
%             write(arduino,3,"uint8");
%             write(arduino,3,"uint8");

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.STARTButton.Visible = 'off';
            app.STOPButton.Visible = 'on';
            start_shock(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          saveStatus(app,'gui_settings_shock.mat');
          delete(app);
          delete(instrfindall);
                       
        end

        % Callback function
        function OFFButton_2Pushed(app, event)
         global arduino;
            fprintf(arduino,'%c','b');

        end

        % Value changed function: shock_before_tone
        function shock_before_toneValueChanged(app, event)
            value = app.shock_before_tone.Value;
            app.tone_before_shock.Value = ~value;
        end

        % Value changed function: tone_before_shock
        function tone_before_shockValueChanged(app, event)
            value = app.tone_before_shock.Value;
            app.shock_before_tone.Value = ~value;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            global arduino ack;
           fprintf(arduino,'%c','z');
            app.STARTButton.Visible = 'on';
            app.STOPButton.Visible = 'off';
            ack = 1;
        end

        % Value changed function: shock_current
        function shock_currentValueChanged(app, event)
            value = app.shock_current.Value;
            app.shock_current.Value = min(5.11,value);

        end

        % Value changed function: volume
        function volumeValueChanged(app, event)
            value = app.volume.Value;
            global arduino;
           fprintf(arduino,'%c',['v' value]);
            
        end

        % Button pushed function: preview
        function previewButtonPushed(app, event)
            camera_preview(app);
        end

        % Button pushed function: dir
        function dirButtonPushed(app, event)
            global dirpath;
                if isempty(dirpath)
                    dirpath = pwd;
                end
                ddir = uigetdir(dirpath);
                app.resdir.Value = [ddir '\'];
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ShockDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.ShockDurationsEditFieldLabel.Text = 'Shock Duration (s)';
            app.shock_duration_min = uieditfield(app.UIFigure, 'numeric');
            app.shock_duration_max = uieditfield(app.UIFigure, 'numeric');
            app.cycle_interval_max = uieditfield(app.UIFigure, 'numeric');
            app.CycleIntervalsLabel = uilabel(app.UIFigure);
            app.CycleIntervalsLabel.Text = 'Cycle Interval (s)';
            app.cycle_interval_min = uieditfield(app.UIFigure, 'numeric');
            app.delay_max = uieditfield(app.UIFigure, 'numeric');
            app.DelaysLabel = uilabel(app.UIFigure);
            app.DelaysLabel.Text = 'Delay  (s)';
            app.delay_min = uieditfield(app.UIFigure, 'numeric');
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NumberOfCyclesLabel = uilabel(app.UIFigure);
            app.NumberOfCyclesLabel.Text = 'Number Of Cycles';
            app.number_of_cycles = uieditfield(app.UIFigure, 'numeric');
            app.Tone1PitchkHzLabel = uilabel(app.UIFigure);
            app.Tone1PitchkHzLabel.Text = 'Tone1 Pitch (kHz)';
            app.tone1_freq = uidropdown(app.UIFigure);
            app.tone1_freq.Items = {'2', '4', '6', '8', '12', '16', '24', '32'};
            app.tone1_freq.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.tone1_freq.Value = '1';
            app.Tone2PitchkHzLabel = uilabel(app.UIFigure);
            app.Tone2PitchkHzLabel.Text = 'Tone2 Pitch (kHz)';
            app.tone2_freq = uidropdown(app.UIFigure);
            app.tone2_freq.Items = {'2', '4', '6', '8', '12', '16', '24', '32'};
            app.tone2_freq.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', ''};
            app.tone2_freq.Value = '1';
            app.tone1 = uicheckbox(app.UIFigure);
            app.tone1.Text = 'Tone1';
            app.tone2 = uicheckbox(app.UIFigure);
            app.tone2.Text = 'Tone2';
            app.tone_duration_max = uieditfield(app.UIFigure, 'numeric');
            app.ToneDurationsLabel = uilabel(app.UIFigure);
            app.ToneDurationsLabel.Text = 'Tone Duration  (s)';
            app.tone_duration_min = uieditfield(app.UIFigure, 'numeric');
            app.shock_before_tone = uicheckbox(app.UIFigure);
            app.shock_before_tone.ValueChangedFcn = createCallbackFcn(app, @shock_before_toneValueChanged, true);
            app.shock_before_tone.Text = 'Shock before Tone';
            app.tone_before_shock = uicheckbox(app.UIFigure);
            app.tone_before_shock.ValueChangedFcn = createCallbackFcn(app, @tone_before_shockValueChanged, true);
            app.tone_before_shock.Text = 'Tone before Shock';
            app.ShockCurrentmALabel = uilabel(app.UIFigure);
            app.ShockCurrentmALabel.Text = 'Shock Current mA';
            app.shock_current = uieditfield(app.UIFigure, 'numeric');
            app.shock_current.ValueChangedFcn = createCallbackFcn(app, @shock_currentValueChanged, true);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ToneVolumeLabel = uilabel(app.UIFigure);
            app.ToneVolumeLabel.Text = 'Tone Volume';
            app.volume = uieditfield(app.UIFigure, 'numeric');
            app.volume.ValueChangedFcn = createCallbackFcn(app, @volumeValueChanged, true);
            app.InitialDelaysLabel = uilabel(app.UIFigure);
            app.InitialDelaysLabel.Text = 'Initial Delay (s)';
            app.initial_delay = uieditfield(app.UIFigure, 'numeric');
            app.preview = uibutton(app.UIFigure, 'push');
            app.preview.ButtonPushedFcn = createCallbackFcn(app, @previewButtonPushed, true);
            app.preview.Text = 'Camera Preview';
            app.save_video = uicheckbox(app.UIFigure);
            app.save_video.Text = 'Save Video';
            app.ResDirEditFieldLabel = uilabel(app.UIFigure);
            app.ResDirEditFieldLabel.Text = 'Res Dir';
            app.resdir = uieditfield(app.UIFigure, 'text');
            app.MouseIDLabel = uilabel(app.UIFigure);
            app.MouseIDLabel.Text = 'Mouse ID';
            app.mouse_id = uieditfield(app.UIFigure, 'text');
            app.dir = uibutton(app.UIFigure, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dirButtonPushed, true);
            app.dir.Text = 'DIR';
            app.Tone2PitchkHzLabel_2 = uilabel(app.UIFigure);
            app.Tone2PitchkHzLabel_2.Text = 'Tone4 Pitch (kHz)';
            app.tone4_freq = uidropdown(app.UIFigure);
            app.tone4_freq.Items = {'2', '4', '6', '8', '12', '16', '24', '32'};
            app.tone4_freq.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', ''};
            app.tone4_freq.Value = '1';
            app.tone4 = uicheckbox(app.UIFigure);
            app.tone4.Text = 'Tone4';
            app.Tone1PitchkHzLabel_2 = uilabel(app.UIFigure);
            app.Tone1PitchkHzLabel_2.Text = 'Tone3 Pitch (kHz)';
            app.tone3_freq = uidropdown(app.UIFigure);
            app.tone3_freq.Items = {'2', '4', '6', '8', '12', '16', '24', '32'};
            app.tone3_freq.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.tone3_freq.Value = '1';
            app.tone3 = uicheckbox(app.UIFigure);
            app.tone3.Text = 'Tone3';
            app.shock1 = uicheckbox(app.UIFigure);
            app.shock1.Text = 'Shock1';
            app.shock2 = uicheckbox(app.UIFigure);
            app.shock2.Text = 'Shock2';
            app.shock3 = uicheckbox(app.UIFigure);
            app.shock3.Text = 'Shock3';
            app.shock4 = uicheckbox(app.UIFigure);
            app.shock4.Text = 'Shock4';
            app.NumberofcyclesleftLabel = uilabel(app.UIFigure);
            app.NumberofcyclesleftLabel.Text = 'Number of cycles left';
            app.TimetonextcycleLabel = uilabel(app.UIFigure);
            app.TimetonextcycleLabel.Text = 'Time to next cycle';
            app.ncs_left = uilabel(app.UIFigure);
            app.ncs_left.Text = '';
            app.time_to_next_cycle = uilabel(app.UIFigure);
            app.time_to_next_cycle.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end